classdef MetodaLuiNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InversulluiNEditField       matlab.ui.control.NumericEditField
        InversulluiNEditFieldLabel  matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CalculeazaButton            matlab.ui.control.Button
        MaxiteratiiEditField        matlab.ui.control.NumericEditField
        MaxiteratiiEditFieldLabel   matlab.ui.control.Label
        EpsilonEditField            matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel       matlab.ui.control.Label
        x0EditField                 matlab.ui.control.NumericEditField
        x0EditFieldLabel            matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        MetodaluiNewtonLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t % Description

        % variabile implicite la rularea aplicatiei
        N = 4;
        x0 = 0.2;
        epsilon = 10.^ -6;
        maxiter = 10;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NEditField.Value = app.N;
            app.x0EditField.Value = app.x0;
            app.EpsilonEditField.Value = app.epsilon;
            app.MaxiteratiiEditField.Value = app.maxiter;
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.N = app.NEditField.Value;
        end

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            % Validare date introduse pentru a rula functia plot
            if app.N > 9 || app.N < 1
               error('N trebuie sa fie mai mic decat 10 sau mai mare decat 0.');
            end
            if app.x0 > 0.49 || app.x0 < 0.01
               error('x0 este invalid.');
            end
            
            % Seteaza estimarea initiala (x0), epsilon, numarul maxim de iteratii
            % si o matrice unde va fi adaugat noul x0 la fiecare iteratie
            array_x = app.x0;
            
            % Defineste functia si derivata sa
            f = @(x) 1/x - app.N;
            df = @(x) -1/x^2;
            
            % Metoda lui Newton
            for niter = 1:app.maxiter
                fx = f(app.x0);
                if abs(fx) < app.epsilon
                    break;
                end
                dfx = df(app.x0);
                app.x0 = app.x0 - fx/dfx;
                
%                 fprintf('Valoarea lui x este %.6f\n', app.x0);
                array_x = [array_x, app.x0];
            end
            
            % Afiseaza rezultatul si numarul de iteratii
%             fprintf('Solutia este x = %f dupa %d iteratii\n', app.x0, niter);
            
            [invN, errors] = newton_results(app.N, array_x(1), app.epsilon, app.maxiter);
%             fprintf('%10s %20s %20s\n', 'Iteratie', 'Solutie', 'Eroare');
%             for i = 1:length(errors)
%                 fprintf('%10d %20.6f %20.6e\n', i-1, array_x(i), errors(i));
%                 if errors(i) < app.epsilon
%                     break;
%                 end
%             end
            
          % Realizarea grafica
          plot(app.UIAxes, 0:niter-1, array_x,'-o', 'LineWidth', 2);

          % Inversul lui N
          app.InversulluiNEditField.Value = invN;

          % Realizare tabel
            app.UITable.Data = [];
            data = get(app.UITable, 'Data');
            for i = 1:length(errors)
                test_err = sprintf('%20.6e', errors(i));
                test_array_x = sprintf('%20.6f', array_x(i));
                test_i = sprintf('%10d', i);
                newRow = {test_i, test_array_x, test_err};
                data = [data; newRow];
            end

            set(app.UITable, 'Data', data);

            % Reinitializare proprietati cu valorile implicite din
            % interfata:
            app.N = app.NEditField.Value;
            app.x0 = app.x0EditField.Value;
            app.epsilon = app.EpsilonEditField.Value;
            app.maxiter = app.MaxiteratiiEditField.Value;
        end

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            app.x0 = app.x0EditField.Value; 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.N = 4;
            app.x0 = 0.2;
            app.NEditField.Value = app.N;
            app.x0EditField.Value = app.x0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reprezentare grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MetodaluiNewtonLabel = uilabel(app.UIFigure);
            app.MetodaluiNewtonLabel.Text = 'Metoda lui Newton';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N:';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0:';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Enable = 'off';
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Enable = 'off';
            app.MaxiteratiiEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiteratiiEditFieldLabel.Enable = 'off';
            app.MaxiteratiiEditFieldLabel.Text = 'Max iteratii';
            app.MaxiteratiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxiteratiiEditField.Enable = 'off';
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Text = 'Calculeaza';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteratii'; 'Solutii'; 'Erori'};
            app.UITable.RowName = {};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InversulluiNEditFieldLabel = uilabel(app.UIFigure);
            app.InversulluiNEditFieldLabel.Text = 'Inversul lui N';
            app.InversulluiNEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodaLuiNewton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end